classdef LaLaLendGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TrackDropDown  matlab.ui.control.DropDown
        TrackLabel     matlab.ui.control.Label
        EditField      matlab.ui.control.EditField
        StopButton     matlab.ui.control.Button
        ExitButton     matlab.ui.control.Button
        PlayButton     matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Choise;
        diskElem;diskElem2;
        se;se2;
        hBlob;
        vidPlayer;
        vidReader;
        stop = false;
        play= false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('cover.png','Parent',app.UIAxes);
            % Create object to read video
            app.vidReader = vision.VideoFileReader('La La Land low.avi');
            app.vidReader.VideoOutputDataType = 'double';
            
            % Create structural element for morphological operations to remove disturbances
            app.diskElem = strel('disk',3);
            app.diskElem2 = strel('disk',3);
            app.se = strel('disk',10);
            app.se2 = strel('disk',13);
            
            % Create a BlobAnanlysis object to calculate detected objects area, centroid, major axis length and label matrix. 
            app.hBlob = vision.BlobAnalysis('MinimumBlobArea',500,'MaximumBlobArea',12000);
            
            % Create VideoPlayer
            app.vidPlayer = vision.DeployableVideoPlayer;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            if app.play == true
                msgbox('Press Stop Before Exiting');
            elseif app.stop == true || app.play == false
                release(app.vidPlayer);
                release(app.vidReader)
                release(app.hBlob)
                close(app.UIFigure);
            end
        end

        % Value changed function: TrackDropDown
        function TrackDropDownValueChanged(app, event)
            app.Choise = app.TrackDropDown.Value;
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.stop = false;
            app.play = true;
            while ~isDone(app.vidReader)
                if app.stop == true
                    break;
                end

                % Read Frame
                vidFrame = step(app.vidReader);
              
                % Convert RGB image to chosen color space
                Ihsv = rgb2hsv(vidFrame);
                % Define thresholds for channel 1 based on histogram settings
                channel1Min = 0.067;
                channel1Max = 0.208;
            
                channel1Min2 = 0.534;
                channel1Max2 = 0.620;
                
                % Define thresholds for channel 2 based on histogram settings
                channel2Min = 0.385;
                channel2Max = 1.000;
            
                channel2Min2 = 0.202;
                channel2Max2 = 0.638;
                
                % Define thresholds for channel 3 based on histogram settings
                channel3Min = 0.000;
                channel3Max = 1.000;
            
                channel3Min2 = 0.387;
                channel3Max2 = 0.833;
            
                % Create mask based on chosen histogram thresholds
                Ibw = (Ihsv(:,:,1) >= channel1Min ) & (Ihsv(:,:,1) <= channel1Max) & ...
                    (Ihsv(:,:,2) >= channel2Min ) & (Ihsv(:,:,2) <= channel2Max) & ...
                    (Ihsv(:,:,3) >= channel3Min ) & (Ihsv(:,:,3) <= channel3Max);
            
                Ibw2 = (Ihsv(:,:,1) >= channel1Min2 ) & (Ihsv(:,:,1) <= channel1Max2) & ...
                (Ihsv(:,:,2) >= channel2Min2 ) & (Ihsv(:,:,2) <= channel2Max2) & ...
                (Ihsv(:,:,3) >= channel3Min2 ) & (Ihsv(:,:,3) <= channel3Max2);
                
                % Use morphological operations to remove disturbances
                Ibwopen = imopen(Ibw,app.diskElem);
                Ibwopen2 = imopen(Ibw2,app.diskElem2);
            
                Ibwdilate=imdilate(Ibwopen,app.se);
                Ibwdilate2=imdilate(Ibwopen2,app.se2);
            
                % Extract the blobs from the frame 
                [~,~,bboxOut] = step(app.hBlob, Ibwdilate);
                [~,~,bboxOut2] = step(app.hBlob, Ibwdilate2);
                
                % Draw a box around the detected objects
                if string(app.Choise) == "Man"
                    output = insertShape(vidFrame,'Rectangle',bboxOut2,'Color','green');
                elseif string(app.Choise) == "Woman"
                    output = insertShape(vidFrame,'Rectangle',bboxOut,'Color','magenta');
                else
                    Ishape = insertShape(vidFrame,'Rectangle',bboxOut,'Color','magenta');
                    output = insertShape(Ishape,'Rectangle',bboxOut2,'Color','green');
                end
                
                %Play in the video player
                step(app.vidPlayer,output); 
                pause(0.009);
            end
            %% Cleanup
             %release(app.vidReader)
             %release(app.hBlob)
             %release(app.vidPlayer)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop = true;
            app.play = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Tracking La La Lend';
            app.TrackLabel = uilabel(app.UIFigure);
            app.TrackLabel.Text = 'Track:';
            app.TrackDropDown = uidropdown(app.UIFigure);
            app.TrackDropDown.Items = {'Both', 'Man', 'Woman'};
            app.TrackDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackDropDownValueChanged, true);
            app.TrackDropDown.Value = 'Both';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LaLaLendGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end